classdef Effects_Variables < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CalculateandPlotLpPsButton  matlab.ui.control.Button
        TKLabel                     matlab.ui.control.Label
        TKEditField                 matlab.ui.control.NumericEditField
        EaLpkcalmol1EditFieldLabel  matlab.ui.control.Label
        EaLpkcalmol1EditField       matlab.ui.control.NumericEditField
        EaPskcalmol1EditFieldLabel  matlab.ui.control.Label
        EaPskcalmol1EditField       matlab.ui.control.NumericEditField
        Lp0matm1min1EditFieldLabel  matlab.ui.control.Label
        Lp0matm1min1EditField       matlab.ui.control.NumericEditField
        Ps0mmin1EditFieldLabel      matlab.ui.control.Label
        Ps0mmin1EditField           matlab.ui.control.NumericEditField
        UIAxes                      matlab.ui.control.UIAxes
        Ratmm3mol1K1Label           matlab.ui.control.Label
        Ratmm3mol1K1EditField       matlab.ui.control.NumericEditField
        Memolm3Label                matlab.ui.control.Label
        Memolm3EditField            matlab.ui.control.NumericEditField
        VbfLabel                    matlab.ui.control.Label
        VbfEditField                matlab.ui.control.NumericEditField
        Vs0m3mol1Label              matlab.ui.control.Label
        Vs0m3mol1EditField          matlab.ui.control.NumericEditField
        Lpatm1min1Label             matlab.ui.control.Label
        Lpatm1min1EditField         matlab.ui.control.NumericEditField
        Psmin1Label                 matlab.ui.control.Label
        Psmin1EditField             matlab.ui.control.NumericEditField
        CalculateandPlotArrheniusButton  matlab.ui.control.Button
        V0m3Label                   matlab.ui.control.Label
        V0m3EditField               matlab.ui.control.NumericEditField
        UITable                     matlab.ui.control.Table
        ClearPlotButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        R1      % 
        R       %
        T0      %
        T       %
        Me      %
        Mn0i    %
        Vbf     %
        Vs0     %
        V0      %
        A       %
        Lp      %
        Ps      %
        Lp0     %
        Ps0     %
        LpA     %
        PsA     %
        Mse     %
        Vb      %
        W       %
        EaLp    %
        EaPs    %
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateandPlotLpPsButton
        function CalculateandPlotLpPsButtonPushed(app, event)
            app.Mn0i = 3*10^-16;
            app.A=((36*pi)^(1/3))*((app.V0)^(2/3));
            app.Mse = app.Me-app.Mn0i;
                      
            app.Vb = app.V0*app.Vbf;
            app.W = app.V0-app.Vb;
            x0 = [app.W,0];
            ts = [0 5];
            f = @(t,x)[-app.Lp*app.A*app.R*app.T*(app.Me-x(2)/(app.Vs0*x(1))-app.Mn0i*(1-app.Vbf)*app.V0/x(1));app.Vs0*app.Ps*app.A*(app.Mse-x(2)/(app.Vs0*x(1)))];
            [t,x] = ode113(f,ts,x0);
            
            Vw = x(:,1);
            Vs = x(:,2);
            
            ti = [0:0.1:5];
            
            Vw = spline(t,Vw,ti);
            Vs = spline(t,Vs,ti);
            
            Msi = Vs./(app.Vs0*Vw)*10^15; 
            
            Vn=[(Vw+Vs+app.Vb)/app.V0];
            
            filename = 'results.xlsx';
            sheet1 = 'fitting_values';
            sheet2 = 'Msi_values';        
            xlswrite (filename,[ti(:),Vn(:)],sheet1);
            xlswrite (filename,[ti(:),Msi(:)],sheet2); 
            
            yyaxis (app.UIAxes,"left")
            app.UIAxes.YLabel.String = 'Vn';
            app.UIAxes.YLim =[0 2];
            plot (app.UIAxes,ti,Vn,'b');
            yyaxis (app.UIAxes,"right")
            app.UIAxes.YLabel.String = 'Msi';
            app.UIAxes.YLim =[0 2];
            plot (app.UIAxes,ti,Msi,'r');
            
            grid(app.UIAxes,"on");
            hold (app.UIAxes,'on'); 
            
            winopen ('results.xlsx'); 
        end

        % Value changed function: TKEditField
        function TKEditFieldValueChanged(app, event)
            value = app.TKEditField.Value;
            app.T = value;
        end

        % Value changed function: EaLpkcalmol1EditField
        function EaLpkcalmol1EditFieldValueChanged(app, event)
            value = app.EaLpkcalmol1EditField.Value;
            app.EaLp = value;
        end

        % Value changed function: EaPskcalmol1EditField
        function EaPskcalmol1EditFieldValueChanged(app, event)
            value = app.EaPskcalmol1EditField.Value;
            app.EaPs = value;
        end

        % Value changed function: Lp0matm1min1EditField
        function Lp0matm1min1EditFieldValueChanged(app, event)
            value = app.Lp0matm1min1EditField.Value;
            app.Lp0 = value;
        end

        % Value changed function: Ps0mmin1EditField
        function Ps0mmin1EditFieldValueChanged(app, event)
            value = app.Ps0mmin1EditField.Value;
            app.Ps0 = value;
        end

        % Callback function
        function LpEditFieldValueChanged(app, event)
            
        end

        % Value changed function: Ratmm3mol1K1EditField
        function Ratmm3mol1K1EditFieldValueChanged(app, event)
            value = app.Ratmm3mol1K1EditField.Value;
            app.R = value;
        end

        % Value changed function: Memolm3EditField
        function Memolm3EditFieldValueChanged(app, event)
            value = app.Memolm3EditField.Value;
            app.Me = value;
        end

        % Value changed function: VbfEditField
        function VbfEditFieldValueChanged(app, event)
            value = app.VbfEditField.Value;
            app.Vbf = value;
        end

        % Value changed function: Vs0m3mol1EditField
        function Vs0m3mol1EditFieldValueChanged(app, event)
            value = app.Vs0m3mol1EditField.Value;
            app.Vs0 = value;
        end

        % Value changed function: V0m3EditField
        function V0m3EditFieldValueChanged(app, event)
            value = app.V0m3EditField.Value;
            app.V0 = value;
        end

        % Value changed function: Lpatm1min1EditField
        function Lpatm1min1EditFieldValueChanged2(app, event)
            value = app.Lpatm1min1EditField.Value;
            app.Lp = value;
        end

        % Value changed function: Psmin1EditField
        function Psmin1EditFieldValueChanged(app, event)
            value = app.Psmin1EditField.Value;
            app.Ps = value;
        end

        % Button pushed function: CalculateandPlotArrheniusButton
        function CalculateandPlotArrheniusButtonPushed(app, event)
        app.T0 = 273.15; % reference value 0C
        app.R1 = 1.987 ; % cal.mol^-1.K^-1  
        app.LpA = app.Lp0*exp((-app.EaLp*10^3/app.R1)*((1/app.T)-(1/app.T0)));
        app.PsA = app.Ps0*exp((-app.EaPs*10^3/app.R1)*((1/app.T)-(1/app.T0)));
        
        app.UITable.Data(:,1) = app.LpA;
        app.UITable.Data(:,2) = app.PsA;
        
        app.Mn0i = 3*10^-15;
        app.A=((36*pi)^(1/3))*((app.V0)^(2/3));
        app.Mse = app.Me-app.Mn0i;
                      
        app.Vb = app.V0*app.Vbf;
        app.W = app.V0-app.Vb;
        x0 = [app.W,0];
        ts = [0 5];
        f = @(t,x)[-app.LpA*app.A*app.R*app.T*(app.Me-x(2)/(app.Vs0*x(1))-app.Mn0i*(1-app.Vbf)*app.V0/x(1));app.Vs0*app.PsA*app.A*(app.Mse-x(2)/(app.Vs0*x(1)))];
        [t,x] = ode113(f,ts,x0);
          
        Vw = x(:,1);
        Vs = x(:,2);
            
        ti = [0:0.1:5];
            
        Vw = spline(t,Vw,ti);
        Vs = spline(t,Vs,ti);
        
        Msi = Vs./(app.Vs0*Vw)*10^15; 
        
        Vn=[(Vw+Vs+app.Vb)/app.V0];
        
        filename = 'results.xlsx';
        sheet1 = 'fitting_values';
        sheet2 = 'Msi_values';        
        xlswrite (filename,[ti(:),Vn(:)],sheet1);
        xlswrite (filename,[ti(:),Msi(:)],sheet2);
        
        yyaxis (app.UIAxes,"left")
        app.UIAxes.YLabel.String = 'Vn';
        app.UIAxes.YLim =[0 2];
        plot (app.UIAxes,ti,Vn,'b');
        yyaxis (app.UIAxes,"right")
        app.UIAxes.YLabel.String = 'Msi';
        app.UIAxes.YLim =[0 2];
        plot (app.UIAxes,ti,Msi,'r');
            
        grid(app.UIAxes,"on");
        hold (app.UIAxes,'on'); 
            
        winopen ('results.xlsx');  
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
        delete results.xlsx    
        cla(app.UIAxes,"reset")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CalculateandPlotLpPsButton = uibutton(app.UIFigure, 'push');
            app.CalculateandPlotLpPsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandPlotLpPsButtonPushed, true);
            app.CalculateandPlotLpPsButton.Text = 'Calculate and Plot [Lp & Ps]';
            app.TKLabel = uilabel(app.UIFigure);
            app.TKLabel.Text = 'T      [K]';
            app.TKEditField = uieditfield(app.UIFigure, 'numeric');
            app.TKEditField.ValueChangedFcn = createCallbackFcn(app, @TKEditFieldValueChanged, true);
            app.EaLpkcalmol1EditFieldLabel = uilabel(app.UIFigure);
            app.EaLpkcalmol1EditFieldLabel.Text = 'EaLp  [kcal*mol^-1]';
            app.EaLpkcalmol1EditField = uieditfield(app.UIFigure, 'numeric');
            app.EaLpkcalmol1EditField.ValueChangedFcn = createCallbackFcn(app, @EaLpkcalmol1EditFieldValueChanged, true);
            app.EaPskcalmol1EditFieldLabel = uilabel(app.UIFigure);
            app.EaPskcalmol1EditFieldLabel.Text = 'EaPs  [kcal*mol^-1]';
            app.EaPskcalmol1EditField = uieditfield(app.UIFigure, 'numeric');
            app.EaPskcalmol1EditField.ValueChangedFcn = createCallbackFcn(app, @EaPskcalmol1EditFieldValueChanged, true);
            app.Lp0matm1min1EditFieldLabel = uilabel(app.UIFigure);
            app.Lp0matm1min1EditFieldLabel.Text = 'Lp0  [µm*atm^-1*min^-1]';
            app.Lp0matm1min1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Lp0matm1min1EditField.ValueChangedFcn = createCallbackFcn(app, @Lp0matm1min1EditFieldValueChanged, true);
            app.Ps0mmin1EditFieldLabel = uilabel(app.UIFigure);
            app.Ps0mmin1EditFieldLabel.Text = 'Ps0  [µm*min^-1]';
            app.Ps0mmin1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Ps0mmin1EditField.ValueChangedFcn = createCallbackFcn(app, @Ps0mmin1EditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'Vn')
            app.Ratmm3mol1K1Label = uilabel(app.UIFigure);
            app.Ratmm3mol1K1Label.Text = 'R[atm*µm^3*mol^-1*K^-1]';
            app.Ratmm3mol1K1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Ratmm3mol1K1EditField.ValueDisplayFormat = '%e';
            app.Ratmm3mol1K1EditField.ValueChangedFcn = createCallbackFcn(app, @Ratmm3mol1K1EditFieldValueChanged, true);
            app.Memolm3Label = uilabel(app.UIFigure);
            app.Memolm3Label.Text = 'Me   [mol*µm^3]';
            app.Memolm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Memolm3EditField.ValueDisplayFormat = '%e';
            app.Memolm3EditField.ValueChangedFcn = createCallbackFcn(app, @Memolm3EditFieldValueChanged, true);
            app.VbfLabel = uilabel(app.UIFigure);
            app.VbfLabel.Text = 'Vbf   [-]';
            app.VbfEditField = uieditfield(app.UIFigure, 'numeric');
            app.VbfEditField.ValueChangedFcn = createCallbackFcn(app, @VbfEditFieldValueChanged, true);
            app.Vs0m3mol1Label = uilabel(app.UIFigure);
            app.Vs0m3mol1Label.Text = 'Vs0  [µm^3*mol^-1]';
            app.Vs0m3mol1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Vs0m3mol1EditField.ValueDisplayFormat = '%e';
            app.Vs0m3mol1EditField.ValueChangedFcn = createCallbackFcn(app, @Vs0m3mol1EditFieldValueChanged, true);
            app.Lpatm1min1Label = uilabel(app.UIFigure);
            app.Lpatm1min1Label.Text = 'Lp    [µ*atm^-1*min^-1]';
            app.Lpatm1min1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Lpatm1min1EditField.ValueChangedFcn = createCallbackFcn(app, @Lpatm1min1EditFieldValueChanged2, true);
            app.Psmin1Label = uilabel(app.UIFigure);
            app.Psmin1Label.Text = 'Ps    [µ*min^-1]';
            app.Psmin1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Psmin1EditField.ValueChangedFcn = createCallbackFcn(app, @Psmin1EditFieldValueChanged, true);
            app.CalculateandPlotArrheniusButton = uibutton(app.UIFigure, 'push');
            app.CalculateandPlotArrheniusButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandPlotArrheniusButtonPushed, true);
            app.CalculateandPlotArrheniusButton.Text = 'Calculate and Plot [Arrhenius]';
            app.V0m3Label = uilabel(app.UIFigure);
            app.V0m3Label.Text = 'V0    [µm^3]';
            app.V0m3EditField = uieditfield(app.UIFigure, 'numeric');
            app.V0m3EditField.ValueChangedFcn = createCallbackFcn(app, @V0m3EditFieldValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Lp'; 'Ps'};
            app.UITable.RowName = {};
            app.ClearPlotButton = uibutton(app.UIFigure, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Text = 'Clear Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Effects_Variables

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end